classdef audacity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChooseFileButton              matlab.ui.control.Button
        HowdoyouwanttoinputaudiofileButtonGroup  matlab.ui.container.ButtonGroup
        RecordaudioButton             matlab.ui.control.RadioButton
        ChoosefromfilesButton         matlab.ui.control.RadioButton
        StopButton                    matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        ChooseFilterButtonGroup       matlab.ui.container.ButtonGroup
        NoFilterButton                matlab.ui.control.RadioButton
        EchoButton                    matlab.ui.control.RadioButton
        FlangeButton                  matlab.ui.control.RadioButton
        FadeInButton                  matlab.ui.control.RadioButton
        FadeOutButton                 matlab.ui.control.RadioButton
        RecordButton                  matlab.ui.control.StateButton
        PauseButton                   matlab.ui.control.Button
        ResumeButton                  matlab.ui.control.Button
        NumberofChannels1or2Label     matlab.ui.control.Label
        NumberofChannelsEditField     matlab.ui.control.NumericEditField
        FadeLengthInSecondsEditFieldLabel  matlab.ui.control.Label
        FadeLengthInSecondsEditField  matlab.ui.control.NumericEditField
        SaveOutputButton              matlab.ui.control.Button
        OutputFileNamewavformatEditFieldLabel  matlab.ui.control.Label
        OutputFileNamewavformatEditField  matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        hasInput = false;
        Fs = 44100;
        noc=1;  %number of input channels
        nob=16; %number of bits per sample
        recObj; % your recording
        myRecording; %recorded audio as an array
        player; %audio player object
        output; %output audio file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.hasInput == true
                if app.NoFilterButton.Value == true
                    plot(app.UIAxes,app.myRecording);
                    app.player = audioplayer(app.myRecording,app.Fs);
                    plot(app.UIAxes,app.myRecording);
                    app.player = audioplayer(app.myRecording,app.Fs);
                    app.output = app.myRecording;
                elseif app.EchoButton.Value == true
                    x = app.myRecording;
                    n = length(app.myRecording); %Get the length of the music file
                    a = 0.5; %Attenuation factor
                    d = 800; %delay
                    y = zeros(n+d,1); %initialize the output music signal
                    xn = padarray(x,[d,0],0,'pre');
                    for i = (d+1):1:n
                        y(i-d,1) = x(i,1) + a*xn(i-d);
                        if app.noc == 2
                            y(i-d,2) = x(i,2) + a*xn(i-d);
                        end
                    end
                    plot(app.UIAxes,y);
                    app.player = audioplayer(y,app.Fs);
                    app.output = y;
                elseif app.FlangeButton.Value == true
                    x = app.myRecording;
                    n = length(x); %get the length of the music file
                    tn = n/app.Fs;
                    f = 0.25; %Frequency of sin wave in Hz
                    t = linspace(0,tn,n);
                    d = 100; %delay factor
                    modsin = sin(2*pi*f*t);
                    modsin1 = round(d*modsin')+d; %variable delay
                    y = zeros(n+d,1); %initialize the output music
                    a = 0.5; %attenuation factor
                    xn = padarray(x,[d,0],0,'pre');
                    for i = (d+1):1:n
                       y(i-d,1) = x(i,1) + a*xn(i-modsin1(i-d));
                       if app.noc == 2
                            y(i-d,2) = x(i,2) + a*xn(i-modsin1(i-d));
                        end
                    end
                    plot(app.UIAxes,y);
                    app.player = audioplayer(y,app.Fs);
                    app.output = y;
                elseif app.FadeInButton.Value == true
                    fadeTime = app.FadeLengthInSecondsEditField.Value;
                    linFadeIn = linspace(0,1,app.Fs*fadeTime);
                    linFadeIn = linFadeIn(:);
                    y = app.myRecording;
                    for sample = 1:length(linFadeIn)
                        y(sample,1) = app.myRecording(sample,1) * linFadeIn(sample,1);
                        if app.noc == 2
                            y(sample,2) = app.myRecording(sample,2) * linFadeIn(sample,1);
                        end
                    end
                    plot(app.UIAxes,y);
                    app.player = audioplayer(y,app.Fs);
                    app.output = y;
                elseif app.FadeOutButton.Value == true
                    y = app.myRecording;
                    n = length(y);
                    fadeTime = app.FadeLengthInSecondsEditField.Value;
                    linFadeOut = linspace(1,0,app.Fs*fadeTime);
                    linFadeOut = linFadeOut(:);
                    for sample = 1:length(linFadeOut)
                        y(n - fadeTime*app.Fs + sample,1) = app.myRecording(n - fadeTime*app.Fs + sample,1) * linFadeOut(sample,1);
                        if app.noc == 2
                            y(n - fadeTime*app.Fs + sample,2) = app.myRecording(n - fadeTime*app.Fs + sample,2) * linFadeOut(sample,1);
                        end
                    end
                    plot(app.UIAxes,y);
                    app.player = audioplayer(y,app.Fs);
                    app.output = y;
                end
                resume(app.player);
            end
        end

        % Selection changed function: 
        % HowdoyouwanttoinputaudiofileButtonGroup
        function HowdoyouwanttoinputaudiofileButtonGroupSelectionChanged(app, event)
            selectedButton = app.HowdoyouwanttoinputaudiofileButtonGroup.SelectedObject;
            if selectedButton == app.RecordaudioButton 
                app.ChooseFileButton.Visible = 'off';
                app.RecordButton.Visible = 'on';
                app.NumberofChannelsEditField.Visible = 'on';
                app.NumberofChannels1or2Label.Visible = 'on';
            else
                app.ChooseFileButton.Visible = 'on';
                app.RecordButton.Visible = 'off';
                app.NumberofChannelsEditField.Visible = 'off';
                app.NumberofChannels1or2Label.Visible = 'off';
            end
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('.','Choose an audio file');
            fullpathname = strcat(pathname, filename);
            [app.myRecording,fs] = audioread(num2str(fullpathname));
            app.Fs = fs;
            app.noc = 2;
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;
            app.hasInput = true;
            app.noc = app.NumberofChannelsEditField.Value;
            if value == 1
                app.RecordButton.Text = 'Stop Recording';
                app.recObj = audiorecorder(app.Fs,app.nob,app.noc);
                record(app.recObj);
            else
                app.RecordButton.Text = 'Record';
                stop(app.recObj);
                app.myRecording = getaudiodata(app.recObj); %getting recorded audio as an array
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.hasInput == true
                stop(app.player);
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.hasInput == true
                pause(app.player);
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            if app.hasInput == true
                resume(app.player);
            end
        end

        % Selection changed function: ChooseFilterButtonGroup
        function ChooseFilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseFilterButtonGroup.SelectedObject;
            if selectedButton == app.FadeInButton || selectedButton == app.FadeOutButton
                app.FadeLengthInSecondsEditField.Visible = 'on';
                app.FadeLengthInSecondsEditFieldLabel.Visible = 'on';
            else
                app.FadeLengthInSecondsEditField.Visible = 'off';
                app.FadeLengthInSecondsEditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
            outFileName = app.OutputFileNamewavformatEditField.Value;
            fullOutFileName = strcat(outFileName, '.wav');
            audiowrite(fullOutFileName,app.output,app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Visible = 'off';
            app.ChooseFileButton.Text = 'Choose File';
            app.HowdoyouwanttoinputaudiofileButtonGroup = uibuttongroup(app.UIFigure);
            app.HowdoyouwanttoinputaudiofileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HowdoyouwanttoinputaudiofileButtonGroupSelectionChanged, true);
            app.HowdoyouwanttoinputaudiofileButtonGroup.Title = 'How do you want to input audio file?';
            app.RecordaudioButton = uiradiobutton(app.HowdoyouwanttoinputaudiofileButtonGroup);
            app.RecordaudioButton.Text = 'Record audio';
            app.RecordaudioButton.Value = true;
            app.ChoosefromfilesButton = uiradiobutton(app.HowdoyouwanttoinputaudiofileButtonGroup);
            app.ChoosefromfilesButton.Text = 'Choose from files';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ChooseFilterButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseFilterButtonGroupSelectionChanged, true);
            app.ChooseFilterButtonGroup.Title = 'Choose Filter';
            app.NoFilterButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.NoFilterButton.Text = 'No Filter';
            app.NoFilterButton.Value = true;
            app.EchoButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.EchoButton.Text = 'Echo';
            app.FlangeButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.FlangeButton.Text = 'Flange';
            app.FadeInButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.FadeInButton.Text = 'Fade In';
            app.FadeOutButton = uiradiobutton(app.ChooseFilterButtonGroup);
            app.FadeOutButton.Text = 'Fade Out';
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.NumberofChannels1or2Label = uilabel(app.UIFigure);
            app.NumberofChannels1or2Label.Text = 'Number of Channels (1 or 2):';
            app.NumberofChannelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofChannelsEditField.Limits = [1 2];
            app.NumberofChannelsEditField.Value = 1;
            app.FadeLengthInSecondsEditFieldLabel = uilabel(app.UIFigure);
            app.FadeLengthInSecondsEditFieldLabel.Visible = 'off';
            app.FadeLengthInSecondsEditFieldLabel.Text = 'Fade Length In Seconds:';
            app.FadeLengthInSecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FadeLengthInSecondsEditField.Limits = [0 Inf];
            app.FadeLengthInSecondsEditField.Visible = 'off';
            app.SaveOutputButton = uibutton(app.UIFigure, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Text = 'Save Output';
            app.OutputFileNamewavformatEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFileNamewavformatEditFieldLabel.Text = 'Output File Name (.wav format):';
            app.OutputFileNamewavformatEditField = uieditfield(app.UIFigure, 'text');
            app.OutputFileNamewavformatEditField.Value = 'out';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output Signal Waveform')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audacity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end